classdef VULCAN_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VULCANGUIPanel                  matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        DrillTab                        matlab.ui.container.Tab
        DrillControlsButtonGroup        matlab.ui.container.ButtonGroup
        DRILLDOWNButton                 matlab.ui.control.ToggleButton
        DRILLUPButton                   matlab.ui.control.ToggleButton
        DRILLONButton                   matlab.ui.control.ToggleButton
        STOPButton_4                    matlab.ui.control.ToggleButton
        ToolChangeButtonGroup           matlab.ui.container.ButtonGroup
        CCWButton                       matlab.ui.control.ToggleButton
        CWButton                        matlab.ui.control.ToggleButton
        toolRealeaseButton              matlab.ui.control.ToggleButton
        toolGrabButton                  matlab.ui.control.ToggleButton
        STOPButton_6                    matlab.ui.control.ToggleButton
        UITable                         matlab.ui.control.Table
        VerticalMotionButtonGroup       matlab.ui.container.ButtonGroup
        DownButton                      matlab.ui.control.ToggleButton
        UpButton                        matlab.ui.control.ToggleButton
        STOPButton_7                    matlab.ui.control.ToggleButton
        DigitalCoreIndexEditFieldLabel  matlab.ui.control.Label
        DigitalCoreIndexEditField       matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        HeatProbeTab                    matlab.ui.container.Tab
        HeatProbeControlsButtonGroup    matlab.ui.container.ButtonGroup
        heaterOnButton                  matlab.ui.control.ToggleButton
        STOPButton_2                    matlab.ui.control.ToggleButton
        AmperageAGaugeLabel             matlab.ui.control.Label
        AmperageAGauge                  matlab.ui.control.SemicircularGauge
        PumpControlButtonGroup          matlab.ui.container.ButtonGroup
        pumpOnButton                    matlab.ui.control.ToggleButton
        STOPButton_5                    matlab.ui.control.ToggleButton
        PumpTab                         matlab.ui.container.Tab
    end

    
    properties (Access = private)
        a; % Serial connection
        h; %digital core live plot
        Port; 
        DigCoreIndex;
        
        timeLogs;
        AmperageLogs;
        TimeSecs;
        Amp;
        
        ToolChange;
        s;
        n; %digital core counter
        t;
        data; %array for getting arduino sensor data
    end
    
    properties (Access = public)
        digital_core_data; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.t = table;
           app.n = 0;
           
           %serial communication
           
           app.a = serialport('COM4',115200);
           configureTerminator(app.a,"CR/LF",255);
           
           %digital core setup
           app.h = animatedline(app.UIAxes);
           app.digital_core_data = cell(100,2); %empty cell array, where 1st column is time started, second is arrays for digital core.
           

        end

        % Callback function: DrillControlsButtonGroup, DrillTab
        function DrillControlsButtonGroupSelectionChanged(app, event)

            app.ToolChange = 0;
            if(app.DigitalCoreIndexEditField.Value)
                app.DigCoreIndex = strcat("DigitalCore", app.DigitalCoreIndexEditField.Value, ".csv");
            else
                app.DigCoreIndex = "DigitalCore.csv";
            end
            if(app.DRILLDOWNButton.Value)
                %write to file
                if(exist(app.DigCoreIndex, 'file'))
                    fileID = fopen(app.DigCoreIndex,'a');
                else
                    fileID = fopen(app.DigCoreIndex,'a');
                    fprintf(fileID,'%11s, %15s, %8s, %11s\n','Duration(s)','Distance(steps)','Force(N)','Amperage(A)');
                end
                
                app.n = app.n+1; %count for digital core tracking
                writeline(app.a,'1');%Start moving downwards!
                
                clearpoints(app.h); %Clear points on plot each time digital core starts
                startTime = datetime; %Establish start-time for this digital core
                    
                %digital core
                flush(app.a,"input"); %clears serial data before starting loop
                                      %this assumes we don't care about
                                      %data when stop button is pressed
                %NOTE: sampling frequency is handled in arduino code
                
                while(app.DRILLDOWNButton.Value)
                    dataString = readline(app.a); %gets data from arduino
                    app.data = str2num(dataString); %Puts data from arduino in array
                    if(isnan(app.data))
                       
                        fprintf(fileID, strcat(dataString, "\n"));
                        
                    else
                    addpoints(app.h, app.data(1),app.data(2))%Add points to animation
                            
                    %Get current time    
                    duration = seconds(datetime - startTime); %Current time - start time]
                    nr = {duration, app.data(3),app.data(2)}; %Could do starttime instead of n
                    app.UITable.Data = [app.t;nr];
                    app.t = app.UITable.Data; %table stores all previous data - Make text file?
                    fprintf(fileID,'%12.3f, %15.3f, %12.3f, %12.3f, %12.3f\n',duration,app.data(1),app.data(2),app.data(3), app.data(4));
                    
                    app.AmperageAGauge.Value = app.data(3);
                    end
                end
                
                fclose(fileID);
            end   
            
            if(app.DRILLUPButton.Value)
                writeline(app.a,'2'); %breaks drill loop
            end
            if(app.DRILLONButton.Value)
                writeline(app.a,'4'); %retract until stoppe 
            elseif(app.STOPButton_4.Value)
                writeline(app.a, '-1'); 
            end
            
        end

        % Callback function
        function SliderValueChanged(app, event)
    
        end

        % Callback function
        function SliderValueChanged2(app, event)
            
        end

        % Callback function
        function SliderValueChanging(app, event)
        
        end

        % Selection changed function: ToolChangeButtonGroup
        function ToolChangeButtonGroupSelectionChanged(app, event)
            %selectedButton = app.ToolChangeButtonGroup.SelectedObject;
            if(app.CCWButton.Value)
                writeline(app.a,'7');
            elseif(app.CWButton.Value)
                writeline(app.a,'6');
            elseif(app.toolRealeaseButton.Value)
                writeline(app.a,'8');
            elseif(app.toolGrabButton.Value)
                writeline(app.a,'9');
            elseif(app.STOPButton_6.Value)
                writeline(app.a, '-1');
            end
        end

        % Selection changed function: HeatProbeControlsButtonGroup
        function HeatProbeControlsButtonGroupSelectionChanged(app, event)
            if(app.heaterOnButton.Value)
                writeline(app.a, '3'); %Turn off z motor
            elseif(app.STOPButton_2.Value)
                writeline(app.a, '-1'); 
            end  
        end

        % Selection changed function: VerticalMotionButtonGroup
        function VerticalMotionButtonGroupSelectionChanged(app, event)
            if(app.DownButton.Value)
                writeline(app.a, '13');
            elseif(app.UpButton.Value)
                writeline(app.a, '12');
            elseif(app.STOPButton_7.Value)
                writeline(app.a, '-1');
            end
            
        end

        % Selection changed function: PumpControlButtonGroup
        function PumpControlButtonGroupSelectionChanged(app, event)
            if(app.pumpOnButton.Value)
                writeline(app.a, '5'); 
            elseif(app.STOPButton_5.Value)
                writeline(app.a, '-1'); 
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VULCANGUIPanel = uipanel(app.UIFigure);
            app.VULCANGUIPanel.Title = 'VULCAN GUI';
            app.TabGroup = uitabgroup(app.VULCANGUIPanel);
            app.DrillTab = uitab(app.TabGroup);
            app.DrillTab.SizeChangedFcn = createCallbackFcn(app, @DrillControlsButtonGroupSelectionChanged, true);
            app.DrillTab.Title = 'Drill';
            app.DrillControlsButtonGroup = uibuttongroup(app.DrillTab);
            app.DrillControlsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DrillControlsButtonGroupSelectionChanged, true);
            app.DrillControlsButtonGroup.Title = 'Drill Controls';
            app.DRILLDOWNButton = uitogglebutton(app.DrillControlsButtonGroup);
            app.DRILLDOWNButton.Text = {'DRILL DOWN'; ''};
            app.DRILLDOWNButton.Value = true;
            app.DRILLUPButton = uitogglebutton(app.DrillControlsButtonGroup);
            app.DRILLUPButton.Text = {'DRILL UP'; ''};
            app.DRILLONButton = uitogglebutton(app.DrillControlsButtonGroup);
            app.DRILLONButton.Text = 'DRILL ON';
            app.STOPButton_4 = uitogglebutton(app.DrillControlsButtonGroup);
            app.STOPButton_4.Text = {'STOP'; ''};
            app.ToolChangeButtonGroup = uibuttongroup(app.DrillTab);
            app.ToolChangeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ToolChangeButtonGroupSelectionChanged, true);
            app.ToolChangeButtonGroup.Title = 'Tool Change';
            app.CCWButton = uitogglebutton(app.ToolChangeButtonGroup);
            app.CCWButton.Text = 'CCW';
            app.CWButton = uitogglebutton(app.ToolChangeButtonGroup);
            app.CWButton.Text = 'CW';
            app.toolRealeaseButton = uitogglebutton(app.ToolChangeButtonGroup);
            app.toolRealeaseButton.Text = 'toolRealease';
            app.toolGrabButton = uitogglebutton(app.ToolChangeButtonGroup);
            app.toolGrabButton.Text = 'toolGrab';
            app.toolGrabButton.Value = true;
            app.STOPButton_6 = uitogglebutton(app.ToolChangeButtonGroup);
            app.STOPButton_6.Text = {'STOP'; ''};
            app.UITable = uitable(app.DrillTab);
            app.UITable.ColumnName = {'Time (s)'; 'Current (A)'; 'WOB (N)'};
            app.UITable.RowName = {};
            app.VerticalMotionButtonGroup = uibuttongroup(app.DrillTab);
            app.VerticalMotionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VerticalMotionButtonGroupSelectionChanged, true);
            app.VerticalMotionButtonGroup.Title = 'Vertical Motion';
            app.DownButton = uitogglebutton(app.VerticalMotionButtonGroup);
            app.DownButton.Text = {'Down'; ''};
            app.DownButton.Value = true;
            app.UpButton = uitogglebutton(app.VerticalMotionButtonGroup);
            app.UpButton.Text = {'Up'; ''};
            app.STOPButton_7 = uitogglebutton(app.VerticalMotionButtonGroup);
            app.STOPButton_7.Text = {'STOP'; ''};
            app.DigitalCoreIndexEditFieldLabel = uilabel(app.DrillTab);
            app.DigitalCoreIndexEditFieldLabel.Text = 'Digital Core Index';
            app.DigitalCoreIndexEditField = uieditfield(app.DrillTab, 'text');
            app.UIAxes = uiaxes(app.DrillTab);
            title(app.UIAxes, 'Digital Core Collection')
            xlabel(app.UIAxes, 'Vertical Distance (Motor steps)')
            ylabel(app.UIAxes, 'Force (N)')
            app.HeatProbeTab = uitab(app.TabGroup);
            app.HeatProbeTab.Title = 'Heat Probe';
            app.HeatProbeControlsButtonGroup = uibuttongroup(app.HeatProbeTab);
            app.HeatProbeControlsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @HeatProbeControlsButtonGroupSelectionChanged, true);
            app.HeatProbeControlsButtonGroup.Title = 'Heat Probe Controls';
            app.heaterOnButton = uitogglebutton(app.HeatProbeControlsButtonGroup);
            app.heaterOnButton.Text = 'heaterOn';
            app.heaterOnButton.Value = true;
            app.STOPButton_2 = uitogglebutton(app.HeatProbeControlsButtonGroup);
            app.STOPButton_2.Text = 'STOP';
            app.AmperageAGaugeLabel = uilabel(app.HeatProbeTab);
            app.AmperageAGaugeLabel.Text = 'Amperage (A)';
            app.AmperageAGauge = uigauge(app.HeatProbeTab, 'semicircular');
            app.AmperageAGauge.Limits = [0 10];
            app.AmperageAGauge.MajorTicks = [1 3 5 7 9];
            app.PumpControlButtonGroup = uibuttongroup(app.HeatProbeTab);
            app.PumpControlButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PumpControlButtonGroupSelectionChanged, true);
            app.PumpControlButtonGroup.Title = 'Pump Control';
            app.pumpOnButton = uitogglebutton(app.PumpControlButtonGroup);
            app.pumpOnButton.Text = 'pumpOn';
            app.pumpOnButton.Value = true;
            app.STOPButton_5 = uitogglebutton(app.PumpControlButtonGroup);
            app.STOPButton_5.Text = 'STOP';
            app.PumpTab = uitab(app.TabGroup);
            app.PumpTab.Title = 'Pump';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VULCAN_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end